classdef start_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ConfigurationGUILabel         matlab.ui.control.Label
        LDropDownLabel                matlab.ui.control.Label
        LDropDown                     matlab.ui.control.DropDown
        RDropDownLabel                matlab.ui.control.Label
        RDropDown                     matlab.ui.control.DropDown
        SelectFolderButton            matlab.ui.control.Button
        SelectimageButton             matlab.ui.control.Button
        StartingPointEditFieldLabel   matlab.ui.control.Label
        StartingPointEditField        matlab.ui.control.NumericEditField
        RenderingModeDropDownLabel    matlab.ui.control.Label
        RenderingModeDropDown         matlab.ui.control.DropDown
        SelectSaveFolderButton        matlab.ui.control.Button
        VirtualBackgroundPathLabel    matlab.ui.control.Label
        SceneFolderPathLabel          matlab.ui.control.Label
        SavePathLabel                 matlab.ui.control.Label
        STOPButton                    matlab.ui.control.Button
        STARTButton                   matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes1                       matlab.ui.control.UIAxes
        G37Label                      matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        SelectedSceneFolderTextAreaLabel  matlab.ui.control.Label
        SelectedSceneFolderTextArea   matlab.ui.control.TextArea
        SelectedbackgroundTextAreaLabel  matlab.ui.control.Label
        SelectedbackgroundTextArea    matlab.ui.control.TextArea
        VideoSaveFolderTextAreaLabel  matlab.ui.control.Label
        VideoSaveFolderTextArea       matlab.ui.control.TextArea
        LoopCheckBox                  matlab.ui.control.CheckBox
    end


    properties (Access = public)
        src
        L
        R
        start
        bg
        mode
        savepath
        vidbg
        already_set_ir
        already_set_bg
        already_set_vid
        looping
        state
        start_running
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % default scene folder as the same folder as current
            app.src = "";
            
            % default camera selection 
            app.L = 1;
            app.R = 2;
            
            % default start point
            app.start = 0; 

            % default virtual background as black image
            app.bg = zeros(600,800,3);
            
            % default rendering mode
            app.mode = "substitute";

            % default video save folder as the same folder as current
            app.savepath = "";
            
            % default setting -> background is still image
            app.vidbg = 0;
            
            % flag for whether the scene folder has already been processed
            app.already_set_ir = 0;
            % flag for whether the virtual background has already been processed
            app.already_set_bg = 0;
            % flag for whether the save video folder has already been processed
            app.already_set_vid = 0;
            
            % default flag, Loop checkbox unticked
            app.looping = 0;
            
            % default flag - program has not started running yet
            app.start_running = 0;

        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            % get the selected scene folder
            app.src = uigetdir();
            % if app.src is not 0 then the user didn't press cancel
            if app.src
                % update the text display to show the selected path
                app.SelectedSceneFolderTextArea.Value = app.src;
                % flag that the path has changed
                app.already_set_ir = 0;
            end
        end

        % Button pushed function: SelectimageButton
        function SelectimageButtonPushed(app, event)
            % get the folder and name of the selected file
            [bg_name,bg_folder] = uigetfile({'*.jpg';'*.png';'*.avi';'*.mp4'});
            % if bg_name is not 0 then the user didn't press cancel
            if bg_name
                % get the full file path of the selected file
                app.bg = fullfile(bg_folder,bg_name);
                % update the text display to show the selected path
                app.SelectedbackgroundTextArea.Value = app.bg;
                % flag that the path has changed
                app.already_set_bg = 0;
                % get the length of the string that is the path
                nameLength = strlength(bg_name);
                % get the last three letters as the file extension
                fileExt = extractBetween(bg_name, nameLength-2, nameLength);
                if strcmp(fileExt{1},'avi') || strcmp(fileExt{1},'mp4')
                    % flag that background is a video file
                    app.vidbg = 1;
                elseif strcmp(fileExt{1},'jpg') || strcmp(fileExt{1},'png')
                    % flag that background is a still image
                    app.vidbg = 0;
                else
                    % throw error for any other files
                    error('File extension must be jpg/png/mp4/avi');
                end
            end
        end

        % Callback function
        function scenenfolderpathEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.Value = get(app.SelectFolderButton,'src',changingValue);
        end

        % Value changed function: LDropDown
        function LDropDownValueChanged(app, event)
            % update the selected left camera
            app.L = str2num(app.LDropDown.Value);
            % flag that we need to update the path
            app.already_set_ir = 0;
        end

        % Value changed function: RDropDown
        function RDropDownValueChanged(app, event)
            % update the selected right camera
            app.R = str2num(app.RDropDown.Value);
            % flag that we need to update the path
            app.already_set_ir = 0;
        end

        % Value changed function: StartingPointEditField
        function StartingPointEditFieldValueChanged(app, event)
            % update the selected start point (0-index)
            app.start = app.StartingPointEditField.Value;
        end

        % Value changed function: RenderingModeDropDown
        function RenderingModeDropDownValueChanged(app, event)
            % update the selected rendering mode
            app.mode = app.RenderingModeDropDown.Value;
        end

        % Button pushed function: SelectSaveFolderButton
        function SelectSaveFolderButtonPushed(app, event)
            % get the selected folder in which to save the output video
            app.savepath = uigetdir();
            % if app.savepath is not 0 then the user didn't press cancel
            if app.savepath
                % update the text display to show the selected path
                app.VideoSaveFolderTextArea.Value = app.savepath;
                % flag that the path has changed
                app.already_set_vid = 0;
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            % set state to 1 (playing video) - every time the start button is pressed
            app.state = 1;
            % only run the main loop the first time start button is pressed
            if ~app.start_running
                % now flag that we've started
                app.start_running = 1;
                % at the beginning, no video output has been saved yet
                videoAlreadyWritten = 0;
                while true 
                    % run only if the scene folder path has changed
                    if ~app.already_set_ir
                        % new folder means we want to write video if not currently doing so
                        if videoAlreadyWritten
                           videoAlreadyWritten = 0;  
                           app.already_set_vid = 0;
                           % also restart background from beginning of video
                           app.already_set_bg = 0;
                        end
                        % grab the first image in folder
                        ir2 = ImageReader(app.src, app.L, app.R, 0, 0);
                        [leftImages,~,~] = next(ir2);
                        % use this image for segmentation
                        background_one = rgb2gray(im2double(uint8(leftImages)));
                        % set up the main ImageReader object
                        ir = ImageReader(app.src, app.L, app.R, app.start, 0);
                        loop = 0;
                        % set flag to 1 so that this section isn't run again if nothing's changed
                        app.already_set_ir = 1;
                    end
                    % run only if the background path has changed
                    if ~app.already_set_bg
                        % new background means we want to write video if not currently doing so
                        if videoAlreadyWritten
                           videoAlreadyWritten = 0;  
                           app.already_set_vid = 0;
                        end
                        % if the background is a video
                        if app.vidbg
                            % VideoReader object
                            v = VideoReader(app.bg);
                            % read the frames of the video
                            vidFrames = read(v);
                            % set the current background frame to index 0
                            bgFrame = 0;
                        else
                            % background is just a still picture
                            bg_pic = imread(app.bg);
                        end
                        % set flag to 1 so that this section isn't run again if nothing's changed
                        app.already_set_bg = 1;
                    end
                    % run only if the video save folder path has changed
                    if ~app.already_set_vid
                        % new folder means we want to write video if not currently doing so
                        if videoAlreadyWritten
                           videoAlreadyWritten = 0;  
                        end
                        % full file path of the output video
                        videoName = fullfile(app.savepath, 'output.avi');
                        % fps of the video
                        fps = 30;
                        aviobj = VideoWriter(videoName);  
                        % set framerate
                        aviobj.FrameRate = fps;
                        % open the VideoWriter object so we can write into it
                        open(aviobj);
                        % set flag to 1 so that this section isn't run again if nothing's changed
                        app.already_set_vid = 1;
                    end
                    
                    % if state is 0, that means stop button has been pressed
                    % if app.looping is 0 that means Loop checkbox is not ticked
                    % videoAlreadyWritten is 1 that means a full video has
                    % already been saved and this is not the first loop
                    if (app.state == 0) || (~app.looping && videoAlreadyWritten)
                        % don't run any more segmentation on any more frames
                        pause(0.1);
                        continue;
                    end
                    % if the background is a video
                    if app.vidbg
                        % increment frame counter by 1
                        bgFrame = bgFrame + 1;
                        % extract the next frame from the background video
                        bg_pic = vidFrames(:,:,:,bgFrame);
                        % if we've reached the end of the background video, loop back to the beginning
                        if bgFrame == v.NumFrames
                            bgFrame = 0;
                        end
                    end
                    [left, right, loop] = next(ir);
                    mask = segmentation(left, background_one);
                    % render the frame with the mask, background and mode
                    renderedFrame = render(left, mask, bg_pic, app.mode);
                    % display left, right cameras and rendered frame
                    imshow(left,'Parent',app.UIAxes1);
                    imshow(renderedFrame,'Parent',app.UIAxes);
                    imshow(right,'Parent',app.UIAxes2);
                    % drop frames if renderer is busy
                    drawnow update
                    % small pause so that this can be interrupted (when stop button is pressed)
                    pause(0.1);
                    % only save the output to video file if this is the first loop
                    if ~videoAlreadyWritten
                        writeVideo(aviobj, renderedFrame);
                    end
                    % when loop as given by next() is 1, we've reached the end of the scene folder
                    if loop
                        % flag that we've finished at least the first loop
                        videoAlreadyWritten = 1;
                        % close the video file
                        close(aviobj);
                    end
                end
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            % set state to 0 to indicate that we have pressed stop
            app.state = 0;
        end

        % Value changed function: LoopCheckBox
        function LoopCheckBoxValueChanged(app, event)
            % update our selection of whether to have looping display
            app.looping = app.LoopCheckBox.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ConfigurationGUILabel = uilabel(app.UIFigure);
            app.ConfigurationGUILabel.Text = 'Configuration GUI';
            app.LDropDownLabel = uilabel(app.UIFigure);
            app.LDropDownLabel.Text = 'L';
            app.LDropDown = uidropdown(app.UIFigure);
            app.LDropDown.Items = {'1', '2'};
            app.LDropDown.ValueChangedFcn = createCallbackFcn(app, @LDropDownValueChanged, true);
            app.LDropDown.Value = '1';
            app.RDropDownLabel = uilabel(app.UIFigure);
            app.RDropDownLabel.Text = 'R';
            app.RDropDown = uidropdown(app.UIFigure);
            app.RDropDown.Items = {'2', '3'};
            app.RDropDown.ValueChangedFcn = createCallbackFcn(app, @RDropDownValueChanged, true);
            app.RDropDown.Value = '2';
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.SelectimageButton = uibutton(app.UIFigure, 'push');
            app.SelectimageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectimageButtonPushed, true);
            app.SelectimageButton.Text = 'Select image';
            app.StartingPointEditFieldLabel = uilabel(app.UIFigure);
            app.StartingPointEditFieldLabel.Text = 'Starting Point';
            app.StartingPointEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartingPointEditField.ValueChangedFcn = createCallbackFcn(app, @StartingPointEditFieldValueChanged, true);
            app.RenderingModeDropDownLabel = uilabel(app.UIFigure);
            app.RenderingModeDropDownLabel.Text = 'Rendering Mode';
            app.RenderingModeDropDown = uidropdown(app.UIFigure);
            app.RenderingModeDropDown.Items = {'foreground', 'background', 'overlay', 'substitute'};
            app.RenderingModeDropDown.ValueChangedFcn = createCallbackFcn(app, @RenderingModeDropDownValueChanged, true);
            app.RenderingModeDropDown.Value = 'substitute';
            app.SelectSaveFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectSaveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSaveFolderButtonPushed, true);
            app.SelectSaveFolderButton.Text = 'Select Save Folder';
            app.VirtualBackgroundPathLabel = uilabel(app.UIFigure);
            app.VirtualBackgroundPathLabel.Text = 'Virtual Background Path';
            app.SceneFolderPathLabel = uilabel(app.UIFigure);
            app.SceneFolderPathLabel.Text = 'Scene Folder Path';
            app.SavePathLabel = uilabel(app.UIFigure);
            app.SavePathLabel.Text = 'Save Path';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Icon = 'oip_stop.jpg';
            app.STOPButton.Text = 'STOP';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Icon = 'OIP_start.jpg';
            app.STARTButton.Text = 'START';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'OUTPUT')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'LEFT')
            xlabel(app.UIAxes1, '')
            ylabel(app.UIAxes1, '')
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.G37Label = uilabel(app.UIFigure);
            app.G37Label.Text = 'G37';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'RIGHT')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.SelectedSceneFolderTextAreaLabel = uilabel(app.UIFigure);
            app.SelectedSceneFolderTextAreaLabel.Text = 'Selected Scene Folder';
            app.SelectedSceneFolderTextArea = uitextarea(app.UIFigure);
            app.SelectedSceneFolderTextArea.Editable = 'off';
            app.SelectedbackgroundTextAreaLabel = uilabel(app.UIFigure);
            app.SelectedbackgroundTextAreaLabel.Text = 'Selected background';
            app.SelectedbackgroundTextArea = uitextarea(app.UIFigure);
            app.SelectedbackgroundTextArea.Editable = 'off';
            app.VideoSaveFolderTextAreaLabel = uilabel(app.UIFigure);
            app.VideoSaveFolderTextAreaLabel.Text = 'Video Save Folder';
            app.VideoSaveFolderTextArea = uitextarea(app.UIFigure);
            app.VideoSaveFolderTextArea.Editable = 'off';
            app.LoopCheckBox = uicheckbox(app.UIFigure);
            app.LoopCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoopCheckBoxValueChanged, true);
            app.LoopCheckBox.Text = 'Loop';
        end
    end

    methods (Access = public)

        % Construct app
        function app = start_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end